classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MATLABGUILabel               matlab.ui.control.Label
        ServerResponseTextArea       matlab.ui.control.TextArea
        ServerResponseTextAreaLabel  matlab.ui.control.Label
        PeriodmsEditField            matlab.ui.control.NumericEditField
        PeriodmsEditFieldLabel       matlab.ui.control.Label
        FlashesEditField             matlab.ui.control.NumericEditField
        FlashesEditFieldLabel        matlab.ui.control.Label
        FlashButton                  matlab.ui.control.Button
        LEDOFFButton                 matlab.ui.control.Button
        LEDONButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LEDONButton
        function LEDONButtonPushed(app, event)
            response = webread("http://137.112.204.113:8080/api/led/on");
            app.ServerResponseTextArea.Value = response;
        end

        % Button pushed function: LEDOFFButton
        function LEDOFFButtonPushed(app, event)
            response = webread("http://137.112.204.113:8080/api/led/off");
            app.ServerResponseTextArea.Value = response;
        end

        % Button pushed function: FlashButton
        function FlashButtonPushed(app, event)
            flashes = app.FlashesEditField.Value;
            period = app.PeriodmsEditField.Value;

            url = "http://137.112.204.113:8080/api/flash/" + flashes + "/" + period;

            response = webread(url);

            app.ServerResponseTextArea.Value = response;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEDONButton = uibutton(app.UIFigure, 'push');
            app.LEDONButton.ButtonPushedFcn = createCallbackFcn(app, @LEDONButtonPushed, true);
            app.LEDONButton.Text = 'LED ON';
            app.LEDOFFButton = uibutton(app.UIFigure, 'push');
            app.LEDOFFButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOFFButtonPushed, true);
            app.LEDOFFButton.Text = 'LED OFF';
            app.FlashButton = uibutton(app.UIFigure, 'push');
            app.FlashButton.ButtonPushedFcn = createCallbackFcn(app, @FlashButtonPushed, true);
            app.FlashButton.Text = 'Flash';
            app.FlashesEditFieldLabel = uilabel(app.UIFigure);
            app.FlashesEditFieldLabel.Text = 'Flashes';
            app.FlashesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodmsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodmsEditFieldLabel.Text = 'Period (ms)';
            app.PeriodmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ServerResponseTextAreaLabel = uilabel(app.UIFigure);
            app.ServerResponseTextAreaLabel.Text = 'Server Response ';
            app.ServerResponseTextArea = uitextarea(app.UIFigure);
            app.MATLABGUILabel = uilabel(app.UIFigure);
            app.MATLABGUILabel.Text = 'MATLAB GUI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end