classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ClearPathButton        matlab.ui.control.Button
        TogglePathButton       matlab.ui.control.Button
        Move3Button            matlab.ui.control.Button
        Move2Button            matlab.ui.control.Button
        Move1Button            matlab.ui.control.Button
        Set3Button             matlab.ui.control.Button
        Set2Button             matlab.ui.control.Button
        Set1Button             matlab.ui.control.Button
        Save3EditField         matlab.ui.control.EditField
        Save3EditFieldLabel    matlab.ui.control.Label
        Save2EditField         matlab.ui.control.EditField
        Save2EditFieldLabel    matlab.ui.control.Label
        Save1EditField         matlab.ui.control.EditField
        Save1EditFieldLabel    matlab.ui.control.Label
        HOMEButton             matlab.ui.control.Button
        GOButton               matlab.ui.control.Button
        J3ValueEditField       matlab.ui.control.NumericEditField
        J3ValueEditFieldLabel  matlab.ui.control.Label
        J2ValueEditField       matlab.ui.control.NumericEditField
        J2ValueEditFieldLabel  matlab.ui.control.Label
        J1ValueEditField       matlab.ui.control.NumericEditField
        J1ValueEditFieldLabel  matlab.ui.control.Label
        Joint3Slider           matlab.ui.control.Slider
        Joint3SliderLabel      matlab.ui.control.Label
        Joint2Slider           matlab.ui.control.Slider
        Joint2SliderLabel      matlab.ui.control.Label
        Joint1Slider           matlab.ui.control.Slider
        Joint1SliderLabel      matlab.ui.control.Label
        axes_arm               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        jointAngles=[0 0 0];
        save1Angles=[0 0 0];
        save2Angles=[0 0 0];
        save3Angles=[0 0 0];
        pathOn = false;
        pathLine
        pathPoints = [];
    end
    
    methods (Access = private)
        
        function updateArm(app)
            A1 = create_A_matrix(0, 60, 0, app.jointAngles(1));
            A2 = create_A_matrix(0, 0, 0, app.jointAngles(2));
            A3 = create_A_matrix(0, 0, 0, app.jointAngles(3));

            % TODO: Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1*create_A_matrix(55.5, 0, 0, 0)*A2;
            T0_3 = A1*create_A_matrix(55.5, 0, 0, 0)*A2*create_A_matrix(0, 40, 0, 0)*create_A_matrix(0, 0, 90, 0)*A3;

            % TODO: Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;

            % TODO: Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
            
            drawPoint=T0_3*[32; -40; 0; 1];

            % Add the point to the path when enabled
            if app.pathOn
                app.pathPoints(end+1,:)=drawPoint(1:3)';
                app.pathLine.XData=app.pathPoints(:,1);
                app.pathLine.YData=app.pathPoints(:,2);
                app.pathLine.ZData=app.pathPoints(:,3);
            end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';

            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            app.axes_arm.XLim = [-200 250];
            app.axes_arm.YLim = [-150 300];
            app.axes_arm.ZLim = [-5 200];

            % Create vertices for all the patches
            makeStlLink("AdeeptL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("AdeeptL1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("AdeeptL2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("AdeeptL3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            hold(app.axes_arm, 'on');
            app.pathLine = plot3(app.axes_arm, NaN, NaN, NaN,'r-', 'LineWidth', 2);

            app.updateArm()
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1)=changingValue;
            app.J1ValueEditField.Value=app.jointAngles(1);
            app.updateArm();
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2)=changingValue;
            app.J2ValueEditField.Value=app.jointAngles(2);
            app.updateArm();
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3)=changingValue;
            app.J3ValueEditField.Value=app.jointAngles(3);
            app.updateArm();
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            app.jointAngles(1)=app.J1ValueEditField.Value;
            app.jointAngles(2)=app.J2ValueEditField.Value;
            app.jointAngles(3)=app.J3ValueEditField.Value;
            app.Joint1Slider.Value=app.J1ValueEditField.Value;
            app.Joint2Slider.Value=app.J2ValueEditField.Value;
            app.Joint3Slider.Value=app.J3ValueEditField.Value;
            app.updateArm()
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.jointAngles(:)=0;
            app.Joint1Slider.Value=0;
            app.Joint2Slider.Value=0;
            app.Joint3Slider.Value=0;
            app.J1ValueEditField.Value=0;
            app.J2ValueEditField.Value=0;
            app.J3ValueEditField.Value=0;
            app.updateArm();
        end

        % Button pushed function: Set1Button
        function Set1ButtonPushed(app, event)
            app.save1Angles=[app.J1ValueEditField.Value,app.J2ValueEditField.Value,app.J3ValueEditField.Value];
            app.Save1EditField.Value=mat2str(app.save1Angles);
        end

        % Button pushed function: Move1Button
        function Move1ButtonPushed(app, event)
            app.jointAngles(1)=app.save1Angles(1);
            app.jointAngles(2)=app.save1Angles(2);
            app.jointAngles(3)=app.save1Angles(3);
            app.Joint1Slider.Value=app.save1Angles(1);
            app.Joint2Slider.Value=app.save1Angles(2);
            app.Joint3Slider.Value=app.save1Angles(3);
            app.J1ValueEditField.Value=app.save1Angles(1);
            app.J2ValueEditField.Value=app.save1Angles(2);
            app.J3ValueEditField.Value=app.save1Angles(3);
            app.updateArm()
        end

        % Button pushed function: Set2Button
        function Set2ButtonPushed(app, event)
            app.save2Angles=[app.J1ValueEditField.Value,app.J2ValueEditField.Value,app.J3ValueEditField.Value];
            app.Save2EditField.Value=mat2str(app.save2Angles);
        end

        % Button pushed function: Move2Button
        function Move2ButtonPushed(app, event)
            app.jointAngles(1)=app.save2Angles(1);
            app.jointAngles(2)=app.save2Angles(2);
            app.jointAngles(3)=app.save2Angles(3);
            app.Joint1Slider.Value=app.save2Angles(1);
            app.Joint2Slider.Value=app.save2Angles(2);
            app.Joint3Slider.Value=app.save2Angles(3);
            app.J1ValueEditField.Value=app.save2Angles(1);
            app.J2ValueEditField.Value=app.save2Angles(2);
            app.J3ValueEditField.Value=app.save2Angles(3);
            app.updateArm()
        end

        % Button pushed function: Set3Button
        function Set3ButtonPushed(app, event)
            app.save3Angles=[app.J1ValueEditField.Value,app.J2ValueEditField.Value,app.J3ValueEditField.Value];
            app.Save3EditField.Value=mat2str(app.save3Angles);
        end

        % Button pushed function: Move3Button
        function Move3ButtonPushed(app, event)
            app.jointAngles(1)=app.save3Angles(1);
            app.jointAngles(2)=app.save3Angles(2);
            app.jointAngles(3)=app.save3Angles(3);
            app.Joint1Slider.Value=app.save3Angles(1);
            app.Joint2Slider.Value=app.save3Angles(2);
            app.Joint3Slider.Value=app.save3Angles(3);
            app.J1ValueEditField.Value=app.save3Angles(1);
            app.J2ValueEditField.Value=app.save3Angles(2);
            app.J3ValueEditField.Value=app.save3Angles(3);
            app.updateArm()
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            if ~ismember(event.Key, {'leftarrow','rightarrow','uparrow','downarrow','a','d'})
                return
            end
            if strcmp(event.Key, 'leftarrow')
                app.jointAngles(1) = app.jointAngles(1) - 5;
            elseif strcmp(event.Key, 'rightarrow')
                app.jointAngles(1) = app.jointAngles(1) + 5;
            elseif strcmp(event.Key, 'a')
                app.jointAngles(2) = app.jointAngles(2) - 5;
            elseif strcmp(event.Key, 'd')
                app.jointAngles(2) = app.jointAngles(2) + 5;
            elseif strcmp(event.Key, 'uparrow')
                app.jointAngles(3) = app.jointAngles(3) + 5;
            elseif strcmp(event.Key, 'downarrow')
                app.jointAngles(3) = app.jointAngles(3) - 5;
            end
            app.jointAngles(1) = max(-180, min(180, app.jointAngles(1)));
            app.jointAngles(2) = max(-150, min(150, app.jointAngles(2)));
            app.jointAngles(3) = max(-20, min(50, app.jointAngles(3)));
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);

            app.J1ValueEditField.Value = app.jointAngles(1);
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.J3ValueEditField.Value = app.jointAngles(3);

            app.updateArm();

        end

        % Button pushed function: TogglePathButton
        function TogglePathButtonPushed(app, event)
            app.pathOn=~app.pathOn;

        end

        % Button pushed function: ClearPathButton
        function ClearPathButtonPushed(app, event)
            app.pathPoints = [];
            app.pathLine.XData = [];
            app.pathLine.YData = [];
            app.pathLine.ZData = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.axes_arm = uiaxes(app.UIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-150 150];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-20 50];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.J1ValueEditFieldLabel = uilabel(app.UIFigure);
            app.J1ValueEditFieldLabel.Text = 'J1 Value ';
            app.J1ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.J2ValueEditFieldLabel = uilabel(app.UIFigure);
            app.J2ValueEditFieldLabel.Text = 'J2 Value ';
            app.J2ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.J3ValueEditFieldLabel = uilabel(app.UIFigure);
            app.J3ValueEditFieldLabel.Text = 'J3 Value ';
            app.J3ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.HOMEButton = uibutton(app.UIFigure, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.Save1EditFieldLabel = uilabel(app.UIFigure);
            app.Save1EditFieldLabel.Text = 'Save 1';
            app.Save1EditField = uieditfield(app.UIFigure, 'text');
            app.Save2EditFieldLabel = uilabel(app.UIFigure);
            app.Save2EditFieldLabel.Text = 'Save 2';
            app.Save2EditField = uieditfield(app.UIFigure, 'text');
            app.Save3EditFieldLabel = uilabel(app.UIFigure);
            app.Save3EditFieldLabel.Text = 'Save 3';
            app.Save3EditField = uieditfield(app.UIFigure, 'text');
            app.Set1Button = uibutton(app.UIFigure, 'push');
            app.Set1Button.ButtonPushedFcn = createCallbackFcn(app, @Set1ButtonPushed, true);
            app.Set1Button.Text = 'Set 1';
            app.Set2Button = uibutton(app.UIFigure, 'push');
            app.Set2Button.ButtonPushedFcn = createCallbackFcn(app, @Set2ButtonPushed, true);
            app.Set2Button.Text = 'Set 2';
            app.Set3Button = uibutton(app.UIFigure, 'push');
            app.Set3Button.ButtonPushedFcn = createCallbackFcn(app, @Set3ButtonPushed, true);
            app.Set3Button.Text = 'Set 3';
            app.Move1Button = uibutton(app.UIFigure, 'push');
            app.Move1Button.ButtonPushedFcn = createCallbackFcn(app, @Move1ButtonPushed, true);
            app.Move1Button.Text = 'Move 1';
            app.Move2Button = uibutton(app.UIFigure, 'push');
            app.Move2Button.ButtonPushedFcn = createCallbackFcn(app, @Move2ButtonPushed, true);
            app.Move2Button.Text = 'Move 2';
            app.Move3Button = uibutton(app.UIFigure, 'push');
            app.Move3Button.ButtonPushedFcn = createCallbackFcn(app, @Move3ButtonPushed, true);
            app.Move3Button.Text = 'Move 3';
            app.TogglePathButton = uibutton(app.UIFigure, 'push');
            app.TogglePathButton.ButtonPushedFcn = createCallbackFcn(app, @TogglePathButtonPushed, true);
            app.TogglePathButton.Text = 'Toggle Path';
            app.ClearPathButton = uibutton(app.UIFigure, 'push');
            app.ClearPathButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPathButtonPushed, true);
            app.ClearPathButton.Text = 'Clear Path';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end