classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Gold                 matlab.ui.control.Image
        Leprechaun           matlab.ui.control.Image
        PercentControlValue  matlab.ui.control.Label
        SuccessValue         matlab.ui.control.Label
        AttemptsValue        matlab.ui.control.Label
        PercentCorrectLabel  matlab.ui.control.Label
        SuccessLabel         matlab.ui.control.Label
        AttemptsLabel        matlab.ui.control.Label
        Button_3             matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        Button               matlab.ui.control.Button
    end

    
    properties (Access = public)
        LeprechaunPosistions
        GoldPositions
        LeprechaunImageSources
        Attempts=0
        Successes=0
    end
    
    methods (Access = public)
        
        function makeAGuess(app, leprechaunindex)
            app.Attempts=app.Attempts + 1;
            app.Leprechaun.Position = app.LeprechaunPosistions(leprechaunindex,:);
            goldIndex=randi([1,3]);
            app.Gold.Position=app.GoldPositions(goldIndex,:);
            if goldIndex==leprechaunindex
                app.Leprechaun.ImageSource=app.LeprechaunImageSources(1);
                app.Successes=app.Successes+1;
            else
               app.Leprechaun.ImageSource=app.LeprechaunImageSources(2);
            end
            app.AttemptsValue.Text=string(app.Attempts);
            app.SuccessValue.Text=string(app.Successes);
            app.PercentControlValue.Text=string(app.Successes/app.Attempts*100);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.LeprechaunImageSources = ["./happyLeprechaun.jpg","./sadLeprechaun.png"];
            app.LeprechaunPosistions(1,:)=[87,240,100,100];
            app.LeprechaunPosistions(2,:)=[271,240,100,100];
            app.LeprechaunPosistions(3,:)=[444,240,100,100];
            app.GoldPositions(1,:)=[87,33,100,100];
            app.GoldPositions(2,:)=[271,33,100,100];
            app.GoldPositions(3,:)=[444,33,100,100];
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           app.makeAGuess(1);
        end

        % Button pushed function: Button_2
        function Button_3Pushed(app, event)
           app.makeAGuess(3);
        end

        % Button pushed function: Button_3
        function Button_2Pushed(app, event)
           app.makeAGuess(2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_2.Text = '3';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_3.Text = '2';
            app.AttemptsLabel = uilabel(app.UIFigure);
            app.AttemptsLabel.Text = 'Attempts: ';
            app.SuccessLabel = uilabel(app.UIFigure);
            app.SuccessLabel.Text = 'Success:';
            app.PercentCorrectLabel = uilabel(app.UIFigure);
            app.PercentCorrectLabel.Text = 'Percent Correct: ';
            app.AttemptsValue = uilabel(app.UIFigure);
            app.AttemptsValue.Text = '0';
            app.SuccessValue = uilabel(app.UIFigure);
            app.SuccessValue.Text = '0';
            app.PercentControlValue = uilabel(app.UIFigure);
            app.PercentControlValue.Text = '0';
            app.Leprechaun = uiimage(app.UIFigure);
            app.Leprechaun.ImageSource = fullfile(pathToMLAPP, 'sadLeprechaun.png');
            app.Gold = uiimage(app.UIFigure);
            app.Gold.ImageSource = fullfile(pathToMLAPP, 'potOfGold.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end